classdef GUIpro1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Panel2                     matlab.ui.container.Panel
        GridLayout                 matlab.ui.container.GridLayout
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
        Panel                      matlab.ui.container.Panel
        PWMSlider                  matlab.ui.control.Slider
        PWMSliderLabel             matlab.ui.control.Label
        DataCollectionSwitch       matlab.ui.control.ToggleSwitch
        DataCollectionSwitchLabel  matlab.ui.control.Label
        EnableSwitch               matlab.ui.control.Switch
        EnableSwitchLabel          matlab.ui.control.Label
        HeatCoolSwitch             matlab.ui.control.Switch
        HeatCoolSwitchLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        Time % Description
        PWMValues
        Temp
        % Replace with the actual COM port of your Arduino
        % comPort = '/dev/cu.usbmodem14101';
        % baudRate = 9600;
        % ser = serialport(comPort, baudRate);
        numValuesToTrack = 100;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Time = zeros(1,app.numValuesToTrack);
            app.PWMValues = zeros(1,app.numValuesToTrack);
        end

        % Value changed function: EnableSwitch
        function EnableSwitchValueChanged(app, event)

            while strcmp(app.EnableSwitch.Value,'On')
                app.PWMValues=[app.PWMValues(2:end),app.PWMSlider.Value];
                app.Time=[app.Time(2:end),app.Time(end)+1];

                plot(app.UIAxes2,app.Time,app.PWMValues)
                axis(app.UIAxes2,[app.Time(1),app.Time(end),-1,40])

                drawnow

            end
        end

        % Value changed function: HeatCoolSwitch
        function HeatCoolSwitchValueChanged(app, event)
       
        end

        % Value changed function: DataCollectionSwitch
        function DataCollectionSwitchValueChanged(app, event)
%             % Define the serial port
% app.comPort = '/dev/cu.usbmodem14101'; % Replace with the actual COM port of your Arduino
% app.baudRate = 9600;
% 
% % Open the serial port connection
% app.ser = serialport(app.comPort, app.baudRate);
% 
% % Create a figure for the strip chart
% figure;
% ax = gca;
% stripChart = animatedline('Color', 'b', 'Marker', 'o');
% xlabel('Time (s)');
% ylabel('Temperature (°C)');
% title('Temperature Strip Chart');
% grid on;
% 
% % Initialize arrays for storing data
% maxPoints = 10; % Maximum number of data points to display
% timeData = zeros(1, maxPoints);
% temperatureData = zeros(1, maxPoints);
% 
% % Read and update the strip chart indefinitely
% while ishandle(ax)
%     % Read data from the serial port
%     data = readline(app.ser);
% 
%     % Check if data is valid (not empty)
%     if ~isempty(data)
%         % Extract numerical values using regular expressions
%         numericValues = str2double(regexp(data, '[-+]?\d*\.?\d+', 'match'));
% 
%         if ~isempty(numericValues) && numel(numericValues) == 2
%             % Append data points to the arrays
%             timeData = [timeData(2:end), numericValues(1)];
%             temperatureData = [temperatureData(2:end), numericValues(2)];
% 
%             % Update the strip chart with the current window of data
%             clearpoints(stripChart);
%             addpoints(stripChart, timeData, temperatureData);
% 
%             % Display the received numerical data with labels
%             fprintf('Time: %.2f s, Temperature: %.2f °C\n', numericValues(1), numericValues(2));
% 
%             % Update the plot
%             drawnow limitrate;
%         else
%             % Display the raw data as is
%             disp(['Received data: ' data]);
%         end
%     end
% end
% 
% % Close the serial port connection when the figure is closed
% app.ser=[];
% 
% clear app.ser;

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.HeatCoolSwitchLabel = uilabel(app.Panel);
            app.HeatCoolSwitchLabel.Text = 'Heat/Cool';
            app.HeatCoolSwitch = uiswitch(app.Panel, 'slider');
            app.HeatCoolSwitch.Items = {'Heat', 'Cool'};
            app.HeatCoolSwitch.ItemsData = [1 0];
            app.HeatCoolSwitch.ValueChangedFcn = createCallbackFcn(app, @HeatCoolSwitchValueChanged, true);
            app.HeatCoolSwitch.Value = 1;
            app.EnableSwitchLabel = uilabel(app.Panel);
            app.EnableSwitchLabel.Text = 'Enable';
            app.EnableSwitch = uiswitch(app.Panel, 'slider');
            app.EnableSwitch.ValueChangedFcn = createCallbackFcn(app, @EnableSwitchValueChanged, true);
            app.DataCollectionSwitchLabel = uilabel(app.Panel);
            app.DataCollectionSwitchLabel.Text = 'Data Collection';
            app.DataCollectionSwitch = uiswitch(app.Panel, 'toggle');
            app.DataCollectionSwitch.ValueChangedFcn = createCallbackFcn(app, @DataCollectionSwitchValueChanged, true);
            app.PWMSliderLabel = uilabel(app.Panel);
            app.PWMSliderLabel.Text = 'PWM';
            app.PWMSlider = uislider(app.Panel);
            app.PWMSlider.Limits = [0 256];
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.GridLayout = uigridlayout(app.Panel2);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Temperature')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'T (C)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'PWM')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Duty Cycle')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIpro1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end